classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AyudaButton                     matlab.ui.control.Button
        HistorialButton                 matlab.ui.control.Button
        InicioButton                    matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        EstadodelaimagenLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        ExportarButton                  matlab.ui.control.Button
        ImportarButton                  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        HerramientasdeRestauracinLabel  matlab.ui.control.Label
        AdicindeColorButton             matlab.ui.control.Button
        DegradacinButton                matlab.ui.control.Button
        ReconstruccinButton             matlab.ui.control.Button
        ManchasButton                   matlab.ui.control.Button
        ArrugasPlieguesButton           matlab.ui.control.Button
        RayonesButton                   matlab.ui.control.Button
        ImagenRestauradaLabel           matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagenOriginal  % Almacena la imagen importada
        ImagenProcesada % Almacena el resultado tras aplicar filtros/módulos
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            fileFilters = {
                '*.png;*.jpg;*.jpeg;*.tif;*.tiff;*.bmp', 'Archivos de imagen (*.png, *.jpg, *.jpeg, *.tif, *.bmp)'; ...
                '*.png',  'Imágenes PNG (*.png)'; ...
                '*.jpg;*.jpeg', 'Imágenes JPEG (*.jpg, *.jpeg)'; ...
                '*.tif;*.tiff', 'Imágenes TIFF (*.tif, *.tiff)'; ...
                '*.bmp',  'Imágenes Bitmap (*.bmp)'; ...
                '*.*',    'Todos los archivos (*.*)'};

            [fileName, folderPath] = uigetfile(fileFilters, 'Seleccionar imagen antigua');

            if isequal(fileName, 0) || isequal(folderPath, 0)
                return; % El usuario canceló la selección
            end
            try
                fullImagePath = fullfile(folderPath, fileName);
                img = imread(fullImagePath);
    
                app.ImagenOriginal = img;
                app.ImagenProcesada = img;
    
                imshow(img, 'Parent', app.UIAxes);
    
                app.EstadodelaimagenLabel.Text = sprintf('Estado: Importación exitosa (%s)', fileName);

            catch ME
                app.EstadodelaimagenLabel.Text = 'Estado: Fallo en la importación de la imagen.';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Panel);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.OriginalLabel = uilabel(app.Panel);
            app.OriginalLabel.Text = 'Original';
            app.ImagenRestauradaLabel = uilabel(app.Panel);
            app.ImagenRestauradaLabel.Text = 'Imagen Restaurada';
            app.Panel_2 = uipanel(app.Panel);
            app.RayonesButton = uibutton(app.Panel_2, 'push');
            app.RayonesButton.Text = 'Rayones';
            app.ArrugasPlieguesButton = uibutton(app.Panel_2, 'push');
            app.ArrugasPlieguesButton.Text = 'Arrugas / Pliegues';
            app.ManchasButton = uibutton(app.Panel_2, 'push');
            app.ManchasButton.Text = 'Manchas';
            app.ReconstruccinButton = uibutton(app.Panel_2, 'push');
            app.ReconstruccinButton.Text = 'Reconstrucción';
            app.DegradacinButton = uibutton(app.Panel_2, 'push');
            app.DegradacinButton.Text = 'Degradación';
            app.AdicindeColorButton = uibutton(app.Panel_2, 'push');
            app.AdicindeColorButton.Text = 'Adición de Color';
            app.HerramientasdeRestauracinLabel = uilabel(app.Panel_2);
            app.HerramientasdeRestauracinLabel.Text = ' Herramientas de Restauración';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Restaura hoy, conserva siempre ';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Inicio';
            app.ImportarButton = uibutton(app.Panel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.ImportarButton.Text = 'Importar';
            app.ExportarButton = uibutton(app.Panel, 'push');
            app.ExportarButton.Text = 'Exportar';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Histograma';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'logofondo.png');
            app.EstadodelaimagenLabel = uilabel(app.UIFigure);
            app.EstadodelaimagenLabel.Text = ' Estado de la imagen';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Recursos', '2.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Recursos', '4.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Recursos', '3.png');
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.Text = 'Inicio';
            app.HistorialButton = uibutton(app.UIFigure, 'push');
            app.HistorialButton.Text = 'Historial';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end